classdef TransformerDesign < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        WidthshouldbezeroifstackingnotpossibleLabel  matlab.ui.control.Label
        Core_murEditField              matlab.ui.control.NumericEditField
        Core_murEditFieldLabel         matlab.ui.control.Label
        Core_VolumeEditField           matlab.ui.control.NumericEditField
        Core_VolumeEditFieldLabel      matlab.ui.control.Label
        Core_ALnHTEditField            matlab.ui.control.NumericEditField
        Core_ALnHTEditFieldLabel       matlab.ui.control.Label
        Core_lnEditField               matlab.ui.control.NumericEditField
        Core_lnEditFieldLabel          matlab.ui.control.Label
        Core_AcwEditField              matlab.ui.control.NumericEditField
        Core_AcwEditFieldLabel         matlab.ui.control.Label
        Core_leEditField               matlab.ui.control.NumericEditField
        Core_leEditFieldLabel          matlab.ui.control.Label
        Core_AEEditField               matlab.ui.control.NumericEditField
        Core_AEEditFieldLabel          matlab.ui.control.Label
        Core_WidthEditField            matlab.ui.control.NumericEditField
        Core_WidthEditFieldLabel       matlab.ui.control.Label
        CorenameEditField              matlab.ui.control.EditField
        CorenameEditFieldLabel         matlab.ui.control.Label
        WindowUtilizationfactorEditField  matlab.ui.control.NumericEditField
        WindowUtilizationfactorEditFieldLabel  matlab.ui.control.Label
        VolumeEditField                matlab.ui.control.NumericEditField
        VolumeEditFieldLabel           matlab.ui.control.Label
        ComputeButton                  matlab.ui.control.Button
        NumberofStackDropDown          matlab.ui.control.DropDown
        NumberofStackDropDownLabel     matlab.ui.control.Label
        IfPossibleEditField            matlab.ui.control.EditField
        IfPossibleEditFieldLabel       matlab.ui.control.Label
        AirgapEditField                matlab.ui.control.NumericEditField
        AirgapEditFieldLabel           matlab.ui.control.Label
        MaximumFluxDensityEditField    matlab.ui.control.NumericEditField
        MaximumFluxDensityEditFieldLabel  matlab.ui.control.Label
        SecondaryTurnsEditField        matlab.ui.control.NumericEditField
        SecondaryTurnsEditFieldLabel   matlab.ui.control.Label
        PrimaryTurnsEditField          matlab.ui.control.NumericEditField
        PrimaryTurnsEditFieldLabel     matlab.ui.control.Label
        LmObtainedEditField            matlab.ui.control.NumericEditField
        LmObtainedEditFieldLabel       matlab.ui.control.Label
        SecondaryWindingResistancemohmEditField  matlab.ui.control.NumericEditField
        SecondaryWindingResistancemohmEditFieldLabel  matlab.ui.control.Label
        PrimaryWindingResistancemohmEditField  matlab.ui.control.NumericEditField
        PrimaryWindingResistancemohmEditFieldLabel  matlab.ui.control.Label
        CoreLossWEditField             matlab.ui.control.NumericEditField
        CoreLossWEditFieldLabel        matlab.ui.control.Label
        SelectCoreDropDown             matlab.ui.control.DropDown
        SelectCoreDropDownLabel        matlab.ui.control.Label
        MagnetizinginductancemHEditField  matlab.ui.control.NumericEditField
        MagnetizinginductancemHEditFieldLabel  matlab.ui.control.Label
        MaximumInputVoltageVEditField  matlab.ui.control.NumericEditField
        MaximumInputVoltageVEditFieldLabel  matlab.ui.control.Label
        SwitchingFrequencyHzEditField  matlab.ui.control.NumericEditField
        SwitchingFrequencyHzEditFieldLabel  matlab.ui.control.Label
        TransformerTurnsratioNpNsEditField  matlab.ui.control.NumericEditField
        TransformerTurnsratioNpNsEditFieldLabel  matlab.ui.control.Label
        SecondaryWinidingCurrentAEditField  matlab.ui.control.NumericEditField
        SecondaryWinidingCurrentAEditFieldLabel  matlab.ui.control.Label
        PrimaryWindingCurrentAEditField  matlab.ui.control.NumericEditField
        PrimaryWindingCurrentAEditFieldLabel  matlab.ui.control.Label
        TransformerDesigntoolLabel     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ComputeButton
        function ComputeButtonPushed(app, event)
            %app.AirgapEditField.Value=5;
            Iprms=app.PrimaryWindingCurrentAEditField.Value;
            Isrms=app.SecondaryWinidingCurrentAEditField.Value;
            Tf_n=app.TransformerTurnsratioNpNsEditField.Value;
            Lm=app.MagnetizinginductancemHEditField.Value;
            fs=app.SwitchingFrequencyHzEditField.Value;
            Vinmax=app.MaximumInputVoltageVEditField.Value;
            Core_name=app.SelectCoreDropDown.Value;
            No_of_Stack=str2num(app.NumberofStackDropDown.Value);

            TF_Cand.Core_name=app.CorenameEditField.Value;
            TF_Cand.mur = app.Core_murEditField.Value; % Relative Permeability
            TF_Cand.Ae = app.Core_AEEditField.Value; % mm^2 %  Effective area of the core
            TF_Cand.le = app.Core_leEditField.Value; % mm % Effective Flux path length
            TF_Cand.AL_onestack = app.Core_ALnHTEditField.Value; % nH/n^2 %  Permeance
            TF_Cand.Volume_onestack = app.Core_VolumeEditField.Value; % mm^3 % Volume of core
            TF_Cand.Acw = app.Core_AcwEditField.Value; % mm^2 % Window area
            TF_Cand.ln = app.Core_lnEditField.Value; % mm % Mean length per turn
            TF_Cand.Core_W = app.Core_WidthEditField.Value; % mm % Core Width 

            %app.IfPossibleEditField.Value=Core_name;
            if(strcmp(Core_name,'Enter Your own')) 
                check=Iprms<0 || Isrms<0 || Lm<0 || fs<0 || Vinmax<0 || TF_Cand.mur<0 || TF_Cand.Ae<0 || TF_Cand.AL_onestack<0 || TF_Cand.Volume_onestack<0 || TF_Cand.ln<0 || TF_Cand.le<=0 || TF_Cand.Acw<=0;
            else
                check=Iprms<0 || Isrms<0 || Lm<0 || fs<0 || Vinmax<0;
            end
            if(check)
                warndlg('Input must be a greater than zero...');
            else
                 if(strcmp(Core_name,'EE-42/21/15'))                             
                    TF_Cand.Core_name='E-42/21/15';
                    TF_Cand.mur = 1778; % Relative Permeability
                    TF_Cand.Ae = 175; % mm^2 %  Effective area of the core
                    TF_Cand.le = 97; % mm % Effective Flux path length
                    TF_Cand.AL_onestack = 4100; % nH/n^2 %  
                    TF_Cand.Volume_onestack = 17300; % mm^3 % Permeance
                    TF_Cand.Acw = 240; % mm^2 % Window area
                    TF_Cand.ln = 90; % mm % Mean length per turn
                    TF_Cand.Core_L = 42; % mm % Core Length
                    TF_Cand.Core_B = 21; % mm % Core Breadth
                    TF_Cand.Core_W = 15; % mm % Core Width
                elseif(strcmp(Core_name,'EE-65/32/27'))
                    TF_Cand.Core_name='E-65/32/27';
                    TF_Cand.mur = 1730; % Relative Permeability
                    TF_Cand.Ae = 529; % mm^2 %  Effective area of the core
                    TF_Cand.le = 147; % mm % Effective Flux path length
                    TF_Cand.AL_onestack = 7900; % nH/n^2 %  
                    TF_Cand.Volume_onestack = 78650; % mm^3 % Permeance
                    TF_Cand.Acw = 435; % mm^2 % Window area
                    TF_Cand.ln = 246; % mm % Mean length per turn
                    TF_Cand.Core_L = 65; % mm % Core Length
                    TF_Cand.Core_B = 32; % mm % Core Breadth
                    TF_Cand.Core_W = 27; % mm % Core Width
                 elseif(strcmp(Core_name,'EE-55/28/25'))
                    TF_Cand.Core_name='E-55/28/25';
                    TF_Cand.mur = 1810; % Relative Permeability
                    TF_Cand.Ae = 420; % mm^2 %  Effective area of the core
                    TF_Cand.le = 124; % mm % Effective Flux path length
                    TF_Cand.AL_onestack = 7700; % nH/n^2 %  Permeance
                    TF_Cand.Volume_onestack = 52100; % mm^3 % Volume of core
                    TF_Cand.Acw = 290; % mm^2 % Window area
                    TF_Cand.ln = 140; % mm % Mean length per turn
                    TF_Cand.Core_L = 55; % mm % Core Length
                    TF_Cand.Core_B = 28; % mm % Core Breadth
                    TF_Cand.Core_W = 25; % mm % Core Width
                 elseif(strcmp(Core_name,'PQ50/50'))
                    TF_Cand.Core_name='PQ50/50';
                    TF_Cand.mur = 1825; % Relative Permeability
                    TF_Cand.Ae = 314; % mm^2 %  Effective area of the core
                    TF_Cand.le = 113; % mm % Effective Flux path length
                    TF_Cand.AL_onestack = 6700; % nH/n^2 %  Permeance
                    TF_Cand.Volume_onestack = 37630; % mm^3 % Volume of core
                    TF_Cand.Acw = 340; % mm^2 % Window area
                    TF_Cand.ln = 100.5; % mm % Mean length per turn
                    TF_Cand.Core_L = 55; % mm % Core Length
                    TF_Cand.Core_B = 28; % mm % Core Breadth
                    TF_Cand.Core_W = 25; % mm % Core Width 
                    No_of_Stack=1;
                 else
                    TF_Cand.Core_name=app.CorenameEditField.Value;
                    TF_Cand.mur = app.Core_murEditField.Value; % Relative Permeability
                    TF_Cand.Ae = app.Core_AEEditField.Value; % mm^2 %  Effective area of the core
                    TF_Cand.le = app.Core_leEditField.Value; % mm % Effective Flux path length
                    TF_Cand.AL_onestack = app.Core_ALnHTEditField.Value; % nH/n^2 %  Permeance
                    TF_Cand.Volume_onestack = app.Core_VolumeEditField.Value; % mm^3 % Volume of core
                    TF_Cand.Acw = app.Core_AcwEditField.Value; % mm^2 % Window area
                    TF_Cand.ln = app.Core_lnEditField.Value; % mm % Mean length per turn
                    TF_Cand.Core_W = app.Core_WidthEditField.Value; % mm % Core Width 
                    %No_of_Stack=1;                    
                 end
                ifpossible=0; 
                Current_density=5; %A/mm2
                if(fs==100e3)
                    Relative_Core_loss_CoeffN97_01_02=[40 300]; % kW/m3
                elseif (fs==150e3)
                    Relative_Core_loss_CoeffN97_01_02=[70 500]; % kW/m3
                else
                    Relative_Core_loss_CoeffN97_01_02=[150 1000]; % kW/m3
                end
                Cu_resistivity=1.72e-8;
                K_wdg_fctr=2.3;
                B_max_allowed=0.25; % Tesla
                L_error=0.5; % mH
                if(Lm<0.5)
                    L_error=0.08;
                end
                mu0 = 4*pi*1e-7; % H/m % Permeability of Free Space
                for j=1:2
                    if(Tf_n>=1) 
                        Ns=1+j-1;
                        Np=Ns*Tf_n;
    
        
                        Required_Window_area_Pri_pturn=Iprms/Current_density; % mm2
                    
                        Required_Window_area_Sec_pturn=Isrms/Current_density; % mm2
                    
                        Required_Window_area_Total=(Required_Window_area_Pri_pturn*Np+Required_Window_area_Sec_pturn*Ns)*K_wdg_fctr; % mm2
                        
                        
                        if(Required_Window_area_Total>TF_Cand.Acw)
                            ifpossible=0;
                            break;
                        else
                            %Core_Loss=Tf_Cand.Volume_onestack*No_of_Stack*10^-9*Relative_Core_loss_CoeffN97_01_02(1)*10^3;
                    
                            Lm_Fund=Np*Np*mu0*TF_Cand.mur*TF_Cand.Ae*No_of_Stack/TF_Cand.le; 
                            % mH % Calculated Magnetizing inductance for above inputs
                    
                            Lm_AL=Np*Np*TF_Cand.AL_onestack*No_of_Stack*1e-6; 
                            % mH % Calculated Magnetizing inductance for above inputs using AL
                            if(abs(Lm_Fund-Lm_AL)>L_error)
                                fprintf('Some thing is wrong\n')
                            else
                                %proceed
                                Volt_sec=Vinmax*0.5/fs; %Vs
                                Im_Del_pp=Volt_sec*1e3/Lm; %A
                                Im_Del_p=Im_Del_pp/2; %A
                                Peak_Amp_turns=Im_Del_p*Np*1e3/TF_Cand.le; % A Turn
                    
                                Flux_density_max=mu0*TF_Cand.mur*1.5*Peak_Amp_turns;
                
                                if(abs(Lm_Fund-Lm)>L_error && Flux_density_max>B_max_allowed)
                                    if(Lm_Fund>Lm)
                                        for lg=0.1:0.05:0.5
                                            Lm_Fund=Np*Np*mu0*TF_Cand.mur*TF_Cand.Ae*No_of_Stack/(TF_Cand.le+lg*TF_Cand.mur);
                                            
                                            Im_Del_pp=Volt_sec*1e3/Lm_Fund; %A
                                            
                                            Im_Del_p=Im_Del_pp/2; %A
                        
                                            Peak_Amp_turns=Im_Del_p*Np*1e3/(TF_Cand.le+lg*TF_Cand.mur);
                                                         
                                            Flux_density_max=mu0*TF_Cand.mur*1.5*Peak_Amp_turns;
                        
                                            if(Flux_density_max<B_max_allowed && abs(Lm_Fund-Lm)<L_error)% && ((Required_Window_area_Total/TF_Cand.Acw)>0.7))
                                                ifpossible=1;
                                                break
                                            end
                                        end
                                    elseif((TF_Cand.Acw/Required_Window_area_Total)>2)
                                        Ns=Ns+1;
                                    end
                                
                                elseif(abs(Lm_Fund-Lm)<L_error && Flux_density_max<B_max_allowed)
                                    ifpossible=1;
                                    break;
                                end
                                if(ifpossible)
                                    break;
                                end
                
                            end
                        end
                    
                    else
                        warndlg('Turns ratio should be Greater than equal to 1');
                        ifpossible=0;break;
                    end
                    
                end
            
                if(ifpossible)
                    app.PrimaryWindingResistancemohmEditField.Enable='on';
                    app.SecondaryWindingResistancemohmEditField.Enable='on';
                    app.MaximumFluxDensityEditField.Enable="on";
                    app.LmObtainedEditField.Enable='on';
                    app.PrimaryTurnsEditField.Enable="on";
                    app.SecondaryTurnsEditField.Enable="on";
                    app.AirgapEditField.Enable="on";
                    app.CoreLossWEditField.Enable="on";
                    app.WindowUtilizationfactorEditField.Enable="on";
                    app.VolumeEditField.Enable="on";

                    app.VolumeEditField.Value=TF_Cand.Volume_onestack*No_of_Stack;
                    app.WindowUtilizationfactorEditField.Value=(Required_Window_area_Total/TF_Cand.Acw);
                    app.IfPossibleEditField.Value='Possible';
                    app.PrimaryWindingResistancemohmEditField.Value=Cu_resistivity*(TF_Cand.ln+(No_of_Stack-1)*2*TF_Cand.Core_W)*Np*1e6/Required_Window_area_Pri_pturn;
                    app.SecondaryWindingResistancemohmEditField.Value=Cu_resistivity*(TF_Cand.ln+(No_of_Stack-1)*2*TF_Cand.Core_W)*Ns*1e6/Required_Window_area_Sec_pturn;
                    
                    app.MaximumFluxDensityEditField.Value=Flux_density_max;
                    app.LmObtainedEditField.Value=Lm_Fund;
                    app.PrimaryTurnsEditField.Value=Np;
                    app.SecondaryTurnsEditField.Value=Ns;
                    app.AirgapEditField.Value=lg;
                    if(Flux_density_max<0.1)
                        k=1;
                    else
                        k=2;
                    end
                    app.CoreLossWEditField.Value=TF_Cand.Volume_onestack*No_of_Stack*10^-9*Relative_Core_loss_CoeffN97_01_02(k)*10^3;
                else
                    app.IfPossibleEditField.Value='Not Possible';
                    app.PrimaryWindingResistancemohmEditField.Enable='off';%Cu_resistivity*(Tf_Cand.ln+(No_of_Stack-1)*2*Tf_Cand.Core_W)*Np*1e6/Required_Window_area_Pri_pturn;
                    app.SecondaryWindingResistancemohmEditField.Enable='off';%Cu_resistivity*(Tf_Cand.ln+(No_of_Stack-1)*2*Tf_Cand.Core_W)*Ns*1e6/Required_Window_area_Sec_pturn;
                    app.MaximumFluxDensityEditField.Enable="off";
                    app.LmObtainedEditField.Enable='off';
                    app.PrimaryTurnsEditField.Enable="off";
                    app.SecondaryTurnsEditField.Enable="off";
                    app.AirgapEditField.Enable="off";
                    app.CoreLossWEditField.Enable="off";
                    app.WindowUtilizationfactorEditField.Enable="off";
                    app.VolumeEditField.Enable="off";
                end
            end          
        end

        % Value changed function: SelectCoreDropDown
        function SelectCoreDropDownValueChanged(app, event)
            Core_name = app.SelectCoreDropDown.Value;

            app.CorenameEditField.Enable="off";
            app.Core_murEditField.Enable="off"; % Relative Permeability
            app.Core_AEEditField.Enable="off"; % mm^2 %  Effective area of the core
            app.Core_leEditField.Enable="off"; % mm % Effective Flux path length
            app.Core_ALnHTEditField.Enable="off"; % nH/n^2 %  Permeance
            app.Core_VolumeEditField.Enable="off"; % mm^3 % Volume of core
            app.Core_AcwEditField.Enable="off"; % mm^2 % Window area
            app.Core_lnEditField.Enable="off"; % mm % Mean length per turn
            app.Core_WidthEditField.Enable="off"; % mm % Core Width 
            if(strcmp(Core_name,'Enter Your own'))
                app.CorenameEditField.Enable="on";
                app.Core_murEditField.Enable="on"; % Relative Permeability
                app.Core_AEEditField.Enable="on"; % mm^2 %  Effective area of the core
                app.Core_leEditField.Enable="on"; % mm % Effective Flux path length
                app.Core_ALnHTEditField.Enable="on"; % nH/n^2 %  Permeance
                app.Core_VolumeEditField.Enable="on"; % mm^3 % Volume of core
                app.Core_AcwEditField.Enable="on"; % mm^2 % Window area
                app.Core_lnEditField.Enable="on"; % mm % Mean length per turn
                app.Core_WidthEditField.Enable="on"; % mm % Core Width 

            end
            if(strcmp(Core_name,'PQ50/50'))
                app.NumberofStackDropDown.Items={'Cannot be stacked'};
            else
                app.NumberofStackDropDown.Items={'1','2'};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TransformerDesigntoolLabel = uilabel(app.UIFigure);
            app.TransformerDesigntoolLabel.Text = 'Transformer Design tool';
            app.PrimaryWindingCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryWindingCurrentAEditFieldLabel.Text = 'Primary Winding Current (A)';
            app.PrimaryWindingCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryWinidingCurrentAEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryWinidingCurrentAEditFieldLabel.Text = 'Secondary Winiding Current (A)';
            app.SecondaryWinidingCurrentAEditField = uieditfield(app.UIFigure, 'numeric');
            app.TransformerTurnsratioNpNsEditFieldLabel = uilabel(app.UIFigure);
            app.TransformerTurnsratioNpNsEditFieldLabel.Text = 'Transformer Turns ratio (Np/Ns)';
            app.TransformerTurnsratioNpNsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SwitchingFrequencyHzEditFieldLabel = uilabel(app.UIFigure);
            app.SwitchingFrequencyHzEditFieldLabel.Text = 'Switching Frequency (Hz)';
            app.SwitchingFrequencyHzEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumInputVoltageVEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumInputVoltageVEditFieldLabel.Text = 'Maximum Input Voltage (V)';
            app.MaximumInputVoltageVEditField = uieditfield(app.UIFigure, 'numeric');
            app.MagnetizinginductancemHEditFieldLabel = uilabel(app.UIFigure);
            app.MagnetizinginductancemHEditFieldLabel.Text = 'Magnetizing inductance (mH)';
            app.MagnetizinginductancemHEditField = uieditfield(app.UIFigure, 'numeric');
            app.SelectCoreDropDownLabel = uilabel(app.UIFigure);
            app.SelectCoreDropDownLabel.Text = 'Select Core';
            app.SelectCoreDropDown = uidropdown(app.UIFigure);
            app.SelectCoreDropDown.Items = {'Enter Your own', 'EE-65/32/27', 'EE-55/28/25', 'EE-42/21/15', 'PQ50/50'};
            app.SelectCoreDropDown.ValueChangedFcn = createCallbackFcn(app, @SelectCoreDropDownValueChanged, true);
            app.SelectCoreDropDown.Value = 'Enter Your own';
            app.CoreLossWEditFieldLabel = uilabel(app.UIFigure);
            app.CoreLossWEditFieldLabel.Text = 'Core Loss (W)';
            app.CoreLossWEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryWindingResistancemohmEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryWindingResistancemohmEditFieldLabel.Text = 'Primary Winding Resistance (mohm)';
            app.PrimaryWindingResistancemohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryWindingResistancemohmEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryWindingResistancemohmEditFieldLabel.Text = 'Secondary Winding Resistance (mohm)';
            app.SecondaryWindingResistancemohmEditField = uieditfield(app.UIFigure, 'numeric');
            app.LmObtainedEditFieldLabel = uilabel(app.UIFigure);
            app.LmObtainedEditFieldLabel.Text = 'Lm Obtained';
            app.LmObtainedEditField = uieditfield(app.UIFigure, 'numeric');
            app.PrimaryTurnsEditFieldLabel = uilabel(app.UIFigure);
            app.PrimaryTurnsEditFieldLabel.Text = 'Primary Turns';
            app.PrimaryTurnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.SecondaryTurnsEditFieldLabel = uilabel(app.UIFigure);
            app.SecondaryTurnsEditFieldLabel.Text = 'Secondary Turns';
            app.SecondaryTurnsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MaximumFluxDensityEditFieldLabel = uilabel(app.UIFigure);
            app.MaximumFluxDensityEditFieldLabel.Text = 'Maximum Flux Density';
            app.MaximumFluxDensityEditField = uieditfield(app.UIFigure, 'numeric');
            app.AirgapEditFieldLabel = uilabel(app.UIFigure);
            app.AirgapEditFieldLabel.Text = 'Air gap';
            app.AirgapEditField = uieditfield(app.UIFigure, 'numeric');
            app.IfPossibleEditFieldLabel = uilabel(app.UIFigure);
            app.IfPossibleEditFieldLabel.Text = 'If Possible';
            app.IfPossibleEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofStackDropDownLabel = uilabel(app.UIFigure);
            app.NumberofStackDropDownLabel.Text = 'Number of Stack';
            app.NumberofStackDropDown = uidropdown(app.UIFigure);
            app.NumberofStackDropDown.Items = {'1', '2'};
            app.NumberofStackDropDown.Value = '1';
            app.ComputeButton = uibutton(app.UIFigure, 'push');
            app.ComputeButton.ButtonPushedFcn = createCallbackFcn(app, @ComputeButtonPushed, true);
            app.ComputeButton.Text = 'Compute';
            app.VolumeEditFieldLabel = uilabel(app.UIFigure);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.WindowUtilizationfactorEditFieldLabel = uilabel(app.UIFigure);
            app.WindowUtilizationfactorEditFieldLabel.Text = 'Window Utilization factor';
            app.WindowUtilizationfactorEditField = uieditfield(app.UIFigure, 'numeric');
            app.CorenameEditFieldLabel = uilabel(app.UIFigure);
            app.CorenameEditFieldLabel.Text = 'Core name';
            app.CorenameEditField = uieditfield(app.UIFigure, 'text');
            app.Core_WidthEditFieldLabel = uilabel(app.UIFigure);
            app.Core_WidthEditFieldLabel.Text = 'Core_Width';
            app.Core_WidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_AEEditFieldLabel = uilabel(app.UIFigure);
            app.Core_AEEditFieldLabel.Text = 'Core_AE';
            app.Core_AEEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_leEditFieldLabel = uilabel(app.UIFigure);
            app.Core_leEditFieldLabel.Text = 'Core_le';
            app.Core_leEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_AcwEditFieldLabel = uilabel(app.UIFigure);
            app.Core_AcwEditFieldLabel.Text = 'Core_Acw';
            app.Core_AcwEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_lnEditFieldLabel = uilabel(app.UIFigure);
            app.Core_lnEditFieldLabel.Text = 'Core_ln';
            app.Core_lnEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_ALnHTEditFieldLabel = uilabel(app.UIFigure);
            app.Core_ALnHTEditFieldLabel.Text = 'Core_AL(nH/T)';
            app.Core_ALnHTEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_VolumeEditFieldLabel = uilabel(app.UIFigure);
            app.Core_VolumeEditFieldLabel.Text = 'Core_Volume';
            app.Core_VolumeEditField = uieditfield(app.UIFigure, 'numeric');
            app.Core_murEditFieldLabel = uilabel(app.UIFigure);
            app.Core_murEditFieldLabel.Text = 'Core_mur';
            app.Core_murEditField = uieditfield(app.UIFigure, 'numeric');
            app.WidthshouldbezeroifstackingnotpossibleLabel = uilabel(app.UIFigure);
            app.WidthshouldbezeroifstackingnotpossibleLabel.Text = 'Width should be zero if stacking not possible';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = TransformerDesign

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end